classdef buildTowerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BuildtowerButton  matlab.ui.control.Button
        JenghisKhanLabel  matlab.ui.control.Label
        ConnectButton     matlab.ui.control.Button
    end


    properties (Access = private)
        serial % Description
    end


    methods (Access = private)

        % Button pushed function: BuildtowerButton
        function BuildtowerButtonPushed(app, event)
           user = 4; 
           serial = app.serial;
           buildtower;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        %% select serial port
        clear serial
        disp('Available COM ports:')
        ports = seriallist();
        options = string(zeros(1, length(ports)));
        for i = 1:length(ports)
            options(i) = string(i) +':' + ports(i);
        end
%         disp(join(options))
%         i = input('Select option:');
        i = 1;
        app.serial = serial(ports(i),'BAUD',57600);
        %% pause to make sure it's opened
        fopen(app.serial);
        pause(1);
        disp('Connected to '+ports(i))
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BuildtowerButton = uibutton(app.UIFigure, 'push');
            app.BuildtowerButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtowerButtonPushed, true);
            app.BuildtowerButton.Text = 'Build tower';
            app.JenghisKhanLabel = uilabel(app.UIFigure);
            app.JenghisKhanLabel.Text = 'Jenghis Khan';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
        end
    end

    methods (Access = public)

        % Construct app
        function app = buildTowerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end